classdef lyh_19011228_term2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_10                    matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        PleaseenterthedesiredvaluesandclickthebuttonLabel  matlab.ui.control.Label
        characteristicTextArea      matlab.ui.control.TextArea
        degLabel_3                  matlab.ui.control.Label
        minelevationEditField       matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        final_epochLabel            matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        initial_epochLabel          matlab.ui.control.Label
        kmLabel                     matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        longitudeEditField          matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        latitudeEditField           matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        altitudeEditField           matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        SYSDropDown                 matlab.ui.control.DropDown
        SYSDropDownLabel            matlab.ui.control.Label
        epochEditField              matlab.ui.control.EditField
        epochEditFieldLabel         matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        LoadButton                  matlab.ui.control.Button
        groundtrackPanel            matlab.ui.container.Panel
        skyplotPanel                matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        nav,initial_time,final_time,time,t,T
        six_parameter,position_ECI,velocity_ECI
        position_ECEF,velocity_ECEF,longitude
        latitude,altitude,satellite_latitude
        satellite_longitude,satellite_altitude
        EAST,NOUTH,UP,azimuth,elevation,gx
        orbit,gs,ac,sat,gt,axes,tt
        % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load('nav.mat');
            app.nav=nav;

            if app.SYSDropDown.Value=="GPS"
                app.initial_time = datetime(app.nav.GPS.toc(1:6));
                app.final_time = datetime(app.nav.GPS.toc(1:6))+days(1);
                app.Label_6.Text=string(app.initial_time);
                app.Label_8.Text=string(app.final_time);
                app.Label_9.Text='Description of GPS';
                app.Label_10.Text='GPS_Velocity in ECEF coordinates';
                app.tt=app.nav.GPS.toc(1:6);
                app.characteristicTextArea.Value="GPS 인공위성 궤도는 지구에서 약 20,200km 떨어진 고도에 위치하고 있으므로 MEO 궤도에 배치되었다고 볼 수 있다. " + ...
                    "또한 밑에 그래프에서도 보이다시피 평균 3.87km/s 속도를 가지고 궤도를 따라 돌고 있습니다." + ...
                    "주기는 약 12시간이다.";

            end
            if app.SYSDropDown.Value=="QZSS"
                app.initial_time = datetime(app.nav.QZSS.toc(1:6));
                app.final_time = datetime(app.nav.QZSS.toc(1:6))+days(1);
                app.Label_6.Text=string(app.initial_time);
                app.Label_8.Text=string(app.final_time);
                app.Label_9.Text='Description of QZSS';
                app.Label_10.Text='QZSS_Velocity in ECEF coordinates';
                app.tt=app.nav.QZSS.toc(1:6);
                app.characteristicTextArea.Value="QZSS 인공위성 궤도는 지구에서 약 35,800km정도 떨어진 고도에 위치하고 있다.     " + ...
                    "                                 따라서 MEO 궤도에 배치되었다고 볼 수 있다. " + ...
                    "                                   또한 밑에 그래프에서도 보이다시피 평균 3.1km/s 속도를 가지고 궤도를 따라 돌고 있습니다." + ...
                    "주기는 약 24시간이다.";
            end
            if app.SYSDropDown.Value=="BDS"
                app.initial_time = datetime(app.nav.BDS.toc(1:6));
                app.final_time = datetime(app.nav.BDS.toc(1:6))+days(1);
                app.Label_6.Text=string(app.initial_time);
                app.Label_8.Text=string(app.final_time);
                app.Label_9.Text="Description of BDS";
                app.Label_10.Text='BDS_Velocity in ECEF coordinates';
                app.tt=app.nav.BDS.toc(1:6);
            
             app.characteristicTextArea.Value="BDS 인공위성 궤도는 지구에서 약 35,800km정도 떨어진 고도에 위치하고 있다.     " + ...
                    "                                 따라서 MEO 궤도에 배치되었다고 볼 수 있다. " + ...
                    "                                   또한 밑에 그래프에서도 보이다 싶이 평균 3.075km/s 속도를 가지고 궤도를 따라 돌고 있습니다." + ...
                    "주기는 약 24시간이다.";
            end
            app.time=app.initial_time+seconds(app.Slider.Value); %원하는시간
            app.epochEditField.Value=string(app.time); %원하는시간 편집창에 시각화 
            
            app.t=app.initial_time:seconds(180):app.time; %처음시간부터 원하는시간까지 2분간격 시간 행렬

            for i= 1:size(app.t,2)
                [y,m,d]=ymd(app.t(i));
                [h,m2,s]=hms(app.t(i));
                app.T=[y, m, d, h, m2, s];
                app.six_parameter=lyh(app.T,app.nav,app.SYSDropDown.Value);
                [app.position_ECI(:,i), app.velocity_ECI(:,i)]=PQW2ECI_value(app.six_parameter);
                [app.position_ECEF(:,i), app.velocity_ECEF(:,i)]=ECI2ECEF_value(app.position_ECI(:,i), app.velocity_ECI(:,i), app.T);
            end
            for s=1:1:size(app.t,2)
                app.axes(s)=norm(app.velocity_ECEF(:,s));
            end
            plot(app.UIAxes,app.t(1:s),app.axes(1:s))
            [y1,m1,d1]=ymd(app.time);
            [h1,m1_,s1]=hms(app.time);
            app.T=[y1, m1, d1, h1, m1_, s1];
            app.six_parameter=lyh(app.T,app.nav,app.SYSDropDown.Value);
            [app.position_ECI(:,i+1), app.velocity_ECI(:,i+1)]=PQW2ECI_value(app.six_parameter);
            [app.position_ECEF(:,i+1), app.velocity_ECEF(:,i+1)]=ECI2ECEF_value(app.position_ECI(:,i+1), app.velocity_ECI(:,i+1), app.T);


            app.longitude=app.latitudeEditField.Value;
            app.latitude=app.longitudeEditField.Value;
            app.altitude=app.altitudeEditField.Value;
            wgs84=wgs84Ellipsoid('kilometer');
            [app.satellite_latitude, app.satellite_longitude, app.satellite_altitude]=ecef2geodetic(wgs84,app.position_ECEF(1,:),app.position_ECEF(2,:),app.position_ECEF(3,:)); 
            [app.EAST,app.NOUTH, app.UP]=ecef2enu(app.position_ECEF(1,:)',app.position_ECEF(2,:)',app.position_ECEF(3,:)',app.latitude,app.longitude,app.altitude,wgs84);
            app.azimuth=azimuth([app.EAST,app.NOUTH,app.UP]);%ENU U가 이상함 여기서부터 다시
            app.elevation=elevation([app.EAST,app.NOUTH,app.UP],app.minelevationEditField.Value);

            for j=1:length(app.elevation)
                for k=1:length(app.elevation)
                    if isnan(app.elevation(k))
                        app.elevation(k)=NaN;
                        app.azimuth(k)=NaN;
                    end
                end
            end
            app.elevation=rmmissing(app.elevation);
            app.azimuth=rmmissing(app.azimuth);


            skyplot(app.skyplotPanel,app.azimuth',app.elevation')

            app.gx =geoaxes(app.groundtrackPanel);
            geoplot(app.gx,app.satellite_latitude,app.satellite_longitude,'.');

            %app.initial_time
            app.orbit = satelliteScenario(app.initial_time,app.final_time,180);
            app.gs = groundStation(app.orbit,app.latitude,app.longitude);
            app.six_parameter=lyh(app.tt,app.nav,app.SYSDropDown.Value);
            app.sat = satellite(app.orbit,app.six_parameter(1)*1000,app.six_parameter(2),app.six_parameter(3),app.six_parameter(4),app.six_parameter(5),app.six_parameter(6));
            app.ac = access(app.sat,app.gs);
            leadTime = 2*24*3600;                                          % seconds
            trailTime = leadTime;
            app.gt=groundTrack(app.sat,"LeadTime",leadTime,"TrailTime",trailTime);
            play(app.orbit)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'Velocity to ECEF')
            zlabel(app.UIAxes, 'Z')
            app.skyplotPanel = uipanel(app.UIFigure);
            app.skyplotPanel.Title = 'sky plot';
            app.groundtrackPanel = uipanel(app.UIFigure);
            app.groundtrackPanel.Title = 'ground track';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 86400];
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [];
            app.Slider.Value = 86400;
            app.epochEditFieldLabel = uilabel(app.UIFigure);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.UIFigure, 'text');
            app.SYSDropDownLabel = uilabel(app.UIFigure);
            app.SYSDropDownLabel.Text = 'SYS';
            app.SYSDropDown = uidropdown(app.UIFigure);
            app.SYSDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.SYSDropDown.Value = 'GPS';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'altitude';
            app.altitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.altitudeEditField.Value = 6378;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.latitudeEditField.Value = 127;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'longitude';
            app.longitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.longitudeEditField.Value = 37;
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.initial_epochLabel = uilabel(app.UIFigure);
            app.initial_epochLabel.Text = 'initial_epoch';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.final_epochLabel = uilabel(app.UIFigure);
            app.final_epochLabel.Text = 'final_epoch';
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.minelevationEditField.Value = 10;
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            app.characteristicTextArea = uitextarea(app.UIFigure);
            app.PleaseenterthedesiredvaluesandclickthebuttonLabel = uilabel(app.UIFigure);
            app.PleaseenterthedesiredvaluesandclickthebuttonLabel.Text = '← Please enter the desired values and click the button.';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lyh_19011228_term2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end